classdef imageGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        imageSlider     matlab.ui.control.Slider
        imNumDisp       matlab.ui.control.Label
        freehand_prop   matlab.ui.control.Button
        freehand        matlab.ui.control.Button
        Difference      matlab.ui.control.CheckBox
        Maximum         matlab.ui.control.CheckBox
        Final           matlab.ui.control.CheckBox
        text3           matlab.ui.control.Label
        diff_c          matlab.ui.control.CheckBox
        Corrected       matlab.ui.control.CheckBox
        resetAll        matlab.ui.control.Button
        resetFollowing  matlab.ui.control.Button
        resetCurrent    matlab.ui.control.Button
        min             matlab.ui.control.EditField
        max             matlab.ui.control.EditField
        adjust          matlab.ui.control.Button
        ResetLUT        matlab.ui.control.Button
        Circle          matlab.ui.control.CheckBox
        PropCircle      matlab.ui.control.StateButton
        imageAxes       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function numImages_CreateFcn(~, hObject, ~, ~)
            if ispc && isequal(get(hObject,'BackgroundColor'),...
                               get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function plot_seg(app, handles, imageNum)
            % Plots midpoint, slice imageNum and different segmentation methods,
            % depending on tick boxes set in GUI.
            plot_string = {'MIDPOINT', 'IMAGE'};
            if handles.Corrected.Value; plot_string{end+1} = 'CORR'; end
            if handles.Difference.Value; plot_string{end+1} = 'DIFF'; end
            if handles.diff_c.Value; plot_string{end+1} = 'DIFFC'; end
            if handles.Final.Value; plot_string{end+1} = 'FINAL'; end
            if handles.Maximum.Value; plot_string{end+1} = 'MAX'; end
            if app.Circle.Value; plot_string{end+1} = 'CIRCLE'; end
            handles.S.plot_segmentations(imageNum, plot_string);
        end
        
        function updateSlider(app, handles)
            % This function updates the slider to have the correct min, max, value, and
            % step size
            % Get the current slice number which was stored in the figure axes, may be
            % empty if the figure has not been initialized, in this case default to 1
            if isempty(getappdata(handles.imageAxes, 'sliceNum'))
                setappdata(handles.imageAxes, 'sliceNum', 1);
            end
            % there are only handles.numImages - 1 images total, because we start at 1
            step = 1/(handles.numImages - 1);
            if step == Inf; step = 1; end
            % set values for the slider bar
            set(handles.imageSlider, 'Max', handles.numImages);
            set(handles.imageSlider, 'Min', 1);
            set(handles.imageSlider, 'SliderStep', [step step]);
            % set current value to the slice we are viewing
            set(handles.imageSlider, 'Value', getappdata(handles.imageAxes, 'sliceNum'));
            handles.imNumDisp.String = [num2str(floor(get(handles.imageSlider, 'Value'))),...
                                        '/', num2str(handles.numImages)];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function imageGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % Choose default command line output for imageGUI
            handles.output = hObject;
            % Update handles structure
            handles.S = varargin{1};
            handles.MergeBuff = handles.S.MergeBuff;
            handles.sz = size(handles.MergeBuff{1});
            handles.image = reshape([handles.MergeBuff{:}], handles.sz(1),...
                                    handles.sz(2), length(handles.MergeBuff));
            handles.numImages = length(handles.MergeBuff);
            updateSlider(app, handles);
            guidata(hObject, handles);
            plot_seg(app, handles, 1);
        end

        % Value changed function: Corrected
        function Corrected_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Hint: get(hObject,'Value') returns toggle state of Corrected
            imageNum = floor(get(handles.imageSlider,'Value'));
            plot_seg(app, handles, imageNum);
        end

        % Value changed function: Difference
        function Difference_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Hint: get(hObject,'Value') returns toggle state of Difference
            imageNum = floor(get(handles.imageSlider,'Value'));
            plot_seg(app, handles, imageNum);
        end

        % Value changed function: Final
        function Final_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Hint: get(hObject,'Value') returns toggle state of Final
            imageNum = floor(get(handles.imageSlider,'Value'));
            plot_seg(app, handles, imageNum);
        end

        % Value changed function: Maximum
        function Maximum_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Hint: get(hObject,'Value') returns toggle state of Maximum
            imageNum = floor(get(handles.imageSlider,'Value'));
            plot_seg(app, handles, imageNum);
        end

        % Button pushed function: ResetLUT
        function ResetLUT_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            imageNum = floor(get(handles.imageSlider,'Value'));
            handles.S.lut = [];
            plot_seg(app, handles, imageNum);
        end

        % Button pushed function: adjust
        function adjust_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            try
                imageNum = floor(get(handles.imageSlider,'Value'));
                handles.S.lut = [str2double(handles.min.String), str2double(handles.max.String)];
                plot_seg(app, handles, imageNum);
            catch
                warning('Check whether min and max are numerical.');
            end
        end

        % Value changed function: diff_c
        function diff_c_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Hint: get(hObject,'Value') returns toggle state of diff_c
            imageNum = floor(get(handles.imageSlider,'Value'));
            plot_seg(app, handles, imageNum);
        end

        % Button pushed function: freehand
        function freehand_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sliceNum = getappdata(handles.imageAxes, 'sliceNum');
            handles.S.correct_segmentation(sliceNum, 0);
            guidata(hObject, handles)
        end

        % Button pushed function: freehand_prop
        function freehand_prop_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sliceNum = getappdata(handles.imageAxes, 'sliceNum');
            if strcmp(handles.S.project_mode, 'internal_no_project')
                warning(['project_mode is set to internal_no_project, using ',...
                         'propagation might lead to unexpected behavior.']);
            end
            handles.S.correct_segmentation(sliceNum, 1);
            guidata(hObject, handles)
        end

        % Value changed function: imageSlider
        function imageSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.imageSlider;
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            % Get current value of the slider
            imageNum = floor(get(hObject,'Value'));
            handles.imNumDisp.String = [num2str(imageNum),...
                                        '/', num2str(handles.numImages)];
            % read in image data
            image = handles.image(:, :, imageNum);
            % bring current axes in focus and show image
            axes(handles.imageAxes);
            plot_seg(app, handles, imageNum);
            %store image data and slice number in axes
            setappdata(handles.imageAxes, 'image',     image);
            setappdata(handles.imageAxes, 'sliceNum',  imageNum);
        end

        % Button pushed function: resetAll
        function resetAll_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.S.thresh_corr = handles.S.thresh_final;
            imageNum = floor(get(handles.imageSlider,'Value'));
            plot_seg(app, handles, imageNum);
        end

        % Button pushed function: resetCurrent
        function resetCurrent_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            imageNum = floor(get(handles.imageSlider,'Value'));
            handles.S.thresh_corr(imageNum) = handles.S.thresh_final(imageNum);
            plot_seg(app, handles, imageNum);
        end

        % Button pushed function: resetFollowing
        function resetFollowing_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            imageNum = floor(get(handles.imageSlider,'Value'));
            handles.S.thresh_corr(imageNum:end) = handles.S.thresh_final(imageNum:end);
            plot_seg(app, handles, imageNum);
        end

        % Value changed function: Circle
        function CircleValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            value = app.Circle.Value;
            imageNum = floor(get(handles.imageSlider,'Value'));
            plot_seg(app, handles, imageNum);
            
        end

        % Value changed function: PropCircle
        function PropCircleValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sliceNum = getappdata(handles.imageAxes, 'sliceNum');
            handles.S.register_circle(sliceNum);
            guidata(hObject, handles);
%             value = app.PropCircle.Value;
        end

        % Window key press function: figure1
        function figure1WindowKeyPress(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            value = getappdata(handles.imageAxes, 'sliceNum');
            key = event.Key;
            if strcmp(key,'leftarrow') && value > 1
            	value = value-1; % left value
            elseif strcmp(key,'rightarrow') && value < handles.numImages
                value = value+1; % right value
            end
            set(handles.imageSlider, 'Value', value);
            app.imageSlider.Value=value;
            imageSlider_Callback(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'imageGUI';
            app.figure1.WindowKeyPressFcn = createCallbackFcn(app, @figure1WindowKeyPress, true);
            app.figure1.Tag = 'figure1';
            app.imageSlider = uislider(app.figure1);
            app.imageSlider.Limits = [0 1];
            app.imageSlider.MajorTicks = [];
            app.imageSlider.ValueChangedFcn = createCallbackFcn(app, @imageSlider_Callback, true);
            app.imageSlider.MinorTicks = [];
            app.imageSlider.Tag = 'imageSlider';
            app.imNumDisp = uilabel(app.figure1);
            app.imNumDisp.Tag = 'imNumDisp';
            app.imNumDisp.Text = '0';
            app.freehand_prop = uibutton(app.figure1, 'push');
            app.freehand_prop.ButtonPushedFcn = createCallbackFcn(app, @freehand_prop_Callback, true);
            app.freehand_prop.Tag = 'freehand_prop';
            app.freehand_prop.Text = 'Use freehand/prop.';
            app.freehand = uibutton(app.figure1, 'push');
            app.freehand.ButtonPushedFcn = createCallbackFcn(app, @freehand_Callback, true);
            app.freehand.Tag = 'freehand';
            app.freehand.Text = 'Use freehand tool';
            app.Difference = uicheckbox(app.figure1);
            app.Difference.ValueChangedFcn = createCallbackFcn(app, @Difference_Callback, true);
            app.Difference.Tag = 'Difference';
            app.Difference.Text = 'Difference';
            app.Maximum = uicheckbox(app.figure1);
            app.Maximum.ValueChangedFcn = createCallbackFcn(app, @Maximum_Callback, true);
            app.Maximum.Tag = 'Maximum';
            app.Maximum.Text = 'Maximum';
            app.Final = uicheckbox(app.figure1);
            app.Final.ValueChangedFcn = createCallbackFcn(app, @Final_Callback, true);
            app.Final.Tag = 'Final';
            app.Final.Text = 'Final';
            app.Final.Value = true;
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Slice no.:';
            app.diff_c = uicheckbox(app.figure1);
            app.diff_c.ValueChangedFcn = createCallbackFcn(app, @diff_c_Callback, true);
            app.diff_c.Tag = 'diff_c';
            app.diff_c.Text = 'Difference corr.';
            app.Corrected = uicheckbox(app.figure1);
            app.Corrected.ValueChangedFcn = createCallbackFcn(app, @Corrected_Callback, true);
            app.Corrected.Tag = 'Corrected';
            app.Corrected.Text = 'Corrected';
            app.Corrected.Value = true;
            app.resetAll = uibutton(app.figure1, 'push');
            app.resetAll.ButtonPushedFcn = createCallbackFcn(app, @resetAll_Callback, true);
            app.resetAll.Tag = 'resetAll';
            app.resetAll.Text = 'Reset all';
            app.resetFollowing = uibutton(app.figure1, 'push');
            app.resetFollowing.ButtonPushedFcn = createCallbackFcn(app, @resetFollowing_Callback, true);
            app.resetFollowing.Tag = 'resetFollowing';
            app.resetFollowing.Text = 'Reset all following';
            app.resetCurrent = uibutton(app.figure1, 'push');
            app.resetCurrent.ButtonPushedFcn = createCallbackFcn(app, @resetCurrent_Callback, true);
            app.resetCurrent.Tag = 'resetCurrent';
            app.resetCurrent.Text = 'Reset current';
            app.min = uieditfield(app.figure1, 'text');
            app.min.Tag = 'min';
            app.min.Value = 'min';
            app.max = uieditfield(app.figure1, 'text');
            app.max.Tag = 'max';
            app.max.Value = 'max';
            app.adjust = uibutton(app.figure1, 'push');
            app.adjust.ButtonPushedFcn = createCallbackFcn(app, @adjust_Callback, true);
            app.adjust.Tag = 'adjust';
            app.adjust.Text = 'Adjust LUT';
            app.ResetLUT = uibutton(app.figure1, 'push');
            app.ResetLUT.ButtonPushedFcn = createCallbackFcn(app, @ResetLUT_Callback, true);
            app.ResetLUT.Tag = 'ResetLUT';
            app.ResetLUT.Text = 'Reset LUT';
            app.Circle = uicheckbox(app.figure1);
            app.Circle.ValueChangedFcn = createCallbackFcn(app, @CircleValueChanged, true);
            app.Circle.Tag = 'checkbox6';
            app.Circle.Text = 'Circle';
            app.PropCircle = uibutton(app.figure1, 'state');
            app.PropCircle.ValueChangedFcn = createCallbackFcn(app, @PropCircleValueChanged, true);
            app.PropCircle.Text = 'Propagate circle';
            app.imageAxes = uiaxes(app.figure1);
            app.imageAxes.Tag = 'imageAxes';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)imageGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end